classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        BlackrockNeurotechTab  matlab.ui.container.Tab
        Button                 matlab.ui.control.Button
        RippleTab              matlab.ui.container.Tab
        PalettePanel           matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
        ObjectContextMenu      matlab.ui.container.ContextMenu
        RemoveMenu             matlab.ui.container.Menu
    end

    
    properties (Access = private)
        newObject % Description
        DynamicComponents      matlab.ui.container.Menu
        ObjectGraph
        ObjectGraphPlot
        ObjectImage
        GraphPlotIndex
        SelectedNode
    end
    
    methods (Access = private)
        function selectObject(app,source,event)
            switch event.Button
                case 1
                    showObjectProperties(app)
                    source.Selected = 'on';
                    cursorCorrection = event.IntersectionPoint(1:2) - [source.XData(1) source.YData(1)];
                    selectImageNode(app,event.IntersectionPoint(1:2));
                    objectSize = [diff(source.XData) diff(source.YData)];
                    app.UIFigure.Pointer = 'fleur';
                    app.UIFigure.WindowButtonMotionFcn = {@app.dragObject,source,objectSize,cursorCorrection};
                    app.UIFigure.WindowButtonUpFcn = {@app.dragObjectDone,source};
                case 2
                case 3
                    selectImageNode(app,event.IntersectionPoint(1:2));

                otherwise
            end
        end

        function dragObject(app,~,~,callingSource,objectSize,cursorCorrection)
            moveDifference = callingSource.Parent.CurrentPoint(1,1:2) - ([callingSource.XData(1) callingSource.YData(1)]+cursorCorrection);
            callingSource.XData = [callingSource.XData(1) + moveDifference(1) callingSource.XData(1) + moveDifference(1) + objectSize(1)];
            callingSource.YData = [callingSource.YData(1) + moveDifference(2) callingSource.YData(1) + moveDifference(2) + objectSize(2)];
            app.ObjectGraphPlot.XData(app.SelectedNode) = sum(callingSource.XData)/2;
            app.ObjectGraphPlot.YData(app.SelectedNode) = sum(callingSource.YData)/2;
        end

        function dragObjectDone(app,~,~,callingSource)
            app.UIFigure.Pointer = 'arrow';
            callingSource.Selected = 'off';
            app.UIFigure.WindowButtonMotionFcn = [];
            app.UIFigure.WindowButtonUpFcn = [];
        end

        function showObjectProperties(app)

        end

        function selectImageNode(app,intersectionPoint)
            [~,app.SelectedNode] = min(pdist2([app.ObjectGraphPlot.XData;app.ObjectGraphPlot.YData]',intersectionPoint));
        end

        function addImageNode(app)
            app.ObjectGraph = addnode(app.ObjectGraph,'Button');
            xdata = [app.ObjectGraphPlot.XData sum(app.newObject.XData)/2];
            ydata = [app.ObjectGraphPlot.YData sum(app.newObject.YData)/2];
            overwriteGraphPlot(app,xdata,ydata)
        end

        function removeImageNode(app)
            app.ObjectGraph = rmnode(app.ObjectGraph,app.SelectedNode);
            xdata = app.ObjectGraphPlot.XData;
            xdata(app.SelectedNode) = [];
            ydata = app.ObjectGraphPlot.YData;
            ydata(app.SelectedNode) = [];
            overwriteGraphPlot(app,xdata,ydata)
        end

        function addEdgeBetweenImageNodes(app)
        end

        function removeEdgeBetweenImageNodes(app)
        end

        function overwriteGraphPlot(app,xdata,ydata)
            delete(app.ObjectGraphPlot)
            app.ObjectGraphPlot = plot(app.UIAxes,app.ObjectGraph,'XData',xdata,'YData',ydata);
            uistack(app.ObjectGraphPlot,'bottom')
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.XColor = [1 1 1];
            app.UIAxes.YColor = [1 1 1];

            app.ObjectGraph = digraph;
            app.ObjectGraphPlot = plot(app.UIAxes,app.ObjectGraph);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            icnMat = imread(event.Source.Icon);
            icnMat = flip(icnMat,1);
            xyCenter = size(icnMat,1,2)./2;
            app.newObject = image(app.UIAxes,icnMat,'XData',[-xyCenter(1) xyCenter(1)],'YData',[-xyCenter(2) xyCenter(2)],'ButtonDownFcn',@app.selectObject);
            app.newObject.ContextMenu = app.ObjectContextMenu;
            
            addImageNode(app)
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            switch key
                case 'f12'
                    if any(contains(event.Modifier,'control'))
                        keyboard
                    end
            end
        end

        % Menu selected function: RemoveMenu
        function deleteSelectedObjectCallback(app, event)
            delete(app.UIFigure.CurrentObject)
            removeImageNode(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.PalettePanel = uipanel(app.UIFigure);
            app.PalettePanel.Title = 'Palette';
            app.UIAxes = uiaxes(app.PalettePanel);
            app.UIAxes.XLim = [-500 500];
            app.UIAxes.YLim = [-500 500];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.YAxisLocation = 'origin';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BlackrockNeurotechTab = uitab(app.TabGroup);
            app.BlackrockNeurotechTab.Title = 'Blackrock Neurotech';
            app.Button = uibutton(app.BlackrockNeurotechTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'BrainViewer', 'Icons', 'brain-24.png');
            app.RippleTab = uitab(app.TabGroup);
            app.RippleTab.Title = 'Ripple';
            app.ObjectContextMenu = uicontextmenu(app.UIFigure);
            app.RemoveMenu = uimenu(app.ObjectContextMenu);
            app.RemoveMenu.MenuSelectedFcn = createCallbackFcn(app, @deleteSelectedObjectCallback, true);
            app.RemoveMenu.Text = 'Remove';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end